classdef SuperSlash < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Step3SelectModelDropDownLabel  matlab.ui.control.Label
        Step3SelectModelDropDown       matlab.ui.control.DropDown
        CalculateButton                matlab.ui.control.Button
        SuperSlashDesignSpaceExplorationforSingleLayerProcessingLabel  matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        Step2ChooseDataReuseStrategyRSDropDownLabel  matlab.ui.control.Label
        Step2ChooseDataReuseStrategyRSDropDown  matlab.ui.control.DropDown
        TotalOffchipMemoryAccessVolumeMBEditFieldLabel  matlab.ui.control.Label
        TotalOffchipMemoryAccessVolumeMBEditField  matlab.ui.control.NumericEditField
        Step1ChooseOnchipBufferSizeKBDropDownLabel  matlab.ui.control.Label
        Step1ChooseOnchipBufferSizeKBDropDown  matlab.ui.control.DropDown
        UITable                        matlab.ui.control.Table
        UITable_2                      matlab.ui.control.Table
        LayerwiseOutputsLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        Image2                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TotalOffchipMemoryAccessVolumeMBEditField.Value = 0;
            app.UITable.Data = [ 32 3 32 32 1 1 1;
                                 zeros(19,numel(app.UITable.ColumnName))];
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure)
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.Image2.Visible=true;
            pause(0.2)
            GBuff = 1024*str2num(app.Step1ChooseOnchipBufferSizeKBDropDown.Value);
            BpW   = 2;
            MB    = 1/1024/1024;
            CNN = app.UITable.Data;
            nnzs = nnz(CNN(:,1));
            CNN = CNN(1:nnzs,:);
            M = CNN(:,1);
            N = CNN(:,2);
            R = CNN(:,3);
            C = CNN(:,4);
            K = CNN(:,5);
            S = CNN(:,6);
            P = CNN(:,7);
            L = size(CNN,1);
            D = 1;
            etaI = D*N.*R.*R.*S.^2;
            etaW = K.*K.*M.*N;
            etaO = D.*M.*R.*R./P.^2;
            rui  = M.*K.^2;
            ruo  = N.*K.^2;
            ruw  = D.*R.*R;
            sumBs = @(l,T_m,T_n,T_r,T_c) (S(l)^2*T_r.*T_c.*T_n)+...
                     (T_r.*T_c.*T_m/P(l)^2)+(K(l)^2*T_m.*T_n);
            IRO=@(l,T_m,T_n,T_r,T_c)...
                etaI(l)+...
                D*etaW(l)*ceil(R(l)./T_r).*ceil(C(l)./T_c)+...
                etaO(l)*(2*ceil(N(l)./T_n)-1);
            ORO=@(l,T_m,T_n,T_r,T_c)...
                etaI(l)*ceil(M(l)./T_m)+...
                D*etaW(l)*ceil(R(l)./T_r).*ceil(C(l)./T_c)+...
                etaO(l);
            WRO=@(l,T_m,T_n,T_r,T_c)...
                etaI(l)*ceil(M(l)./T_m)+...
                etaW(l)+...
                etaO(l)*(2*ceil(N(l)./T_n)-1);

            for l = 1:L
                MM = zeros(M(l),N(l),R(l),C(l));
                NN = zeros(M(l),N(l),R(l),C(l));
                RR = zeros(M(l),N(l),R(l),C(l));
                CC = zeros(M(l),N(l),R(l),C(l));
                for g = 1:M(l)
                    for h = 1:N(l)
                        for i = 1:R(l)
                            for j = 1:C(l)
                                MM(g,h,i,j) = g;
                                NN(g,h,i,j) = h;
                                RR(g,h,i,j) = i;
                                CC(g,h,i,j) = j;
                            end
                        end
                    end
                end
                B     = BpW*sumBs(l,MM,NN,RR,CC);
                B     = B.* (B <= GBuff);
                % IRO
                V_IRO = BpW*IRO(l,MM,NN,RR,CC).*(B>0);
                V_IRO(V_IRO==0) = NaN;
                [min_i(l), min_iindex] = nanmin(V_IRO(:));
                [mi(l),ni(l),ri(l),ci(l)] = ind2sub(size(V_IRO),min_iindex);
                B_IRO(l) = B(mi(l),ni(l),ri(l),ci(l));
                
                % ORO
                V_ORO = BpW*ORO(l,MM,NN,RR,CC).*(B>0);
                V_ORO(V_ORO==0) = NaN;
                [min_o(l), min_oindex] = nanmin(V_ORO(:));
                [mo(l),no(l),ro(l),co(l)] = ind2sub(size(V_ORO),min_oindex);
                B_ORO(l) = B(mo(l),no(l),ro(l),co(l));
                
                % WRO
                V_WRO = BpW*WRO(l,MM,NN,RR,CC).*(B>0);
                V_WRO(V_WRO==0) = NaN;
                [min_w(l), min_windex] = nanmin(V_WRO(:));
                [mw(l),nw(l),rw(l),cw(l)] = ind2sub(size(V_WRO),min_windex);
                B_WRO(l) = B(mw(l),nw(l),rw(l),cw(l));
                if(ruw(l) >= rui(l) && ruw(l) >= ruo(l))
                    Vmin(l) = min_w(l);
                    RS(l) = 3;
                    mv(l) = mw(l);
                    nv(l) = nw(l);
                    rv(l) = rw(l);
                    cv(l) = cw(l);
                    Bv(l) = B_WRO(l);
                elseif(ruo(l) >= rui(l) && ruo(l) >= ruw(l))
                    Vmin(l) = min_o(l);
                    RS(l) = 2;
                    mv(l) = mo(l);
                    nv(l) = no(l);
                    rv(l) = ro(l);
                    cv(l) = co(l);
                    Bv(l) = B_ORO(l);
                else
                    Vmin(l) = min_i(l);
                    RS(l) = 1;
                    mv(l) = mi(l);
                    nv(l) = ni(l);
                    rv(l) = ri(l);
                    cv(l) = ci(l);
                    Bv(l) = B_IRO(l);
                end
            end
            if(app.Step2ChooseDataReuseStrategyRSDropDown.Value=="1-IRO")
                bar(app.UIAxes,min_i)
                bar(app.UIAxes_2,1/1024*B_IRO)
                V = MB*sum(min_i);
                app.UITable_2.Data = [mi',ni',ri',ci', 1/1024*B_IRO',MB*min_i', 1*ones(L,1)];
            elseif(app.Step2ChooseDataReuseStrategyRSDropDown.Value=="2-ORO")
                bar(app.UIAxes,min_o)
                bar(app.UIAxes_2,1/1024*B_ORO)
                V = MB*sum(min_o);
                app.UITable_2.Data = [mo',no',ro',co', 1/1024*B_ORO',MB*min_o', 2*ones(L,1)];
            elseif(app.Step2ChooseDataReuseStrategyRSDropDown.Value=="3-WRO")
                bar(app.UIAxes,min_w)
                bar(app.UIAxes_2,1/1024*B_WRO)
                V = MB*sum(min_w);
                app.UITable_2.Data = [mw',nw',rw',cw', 1/1024*B_WRO',MB*min_w', 3*ones(L,1)];
            else
                bar(app.UIAxes,MB*Vmin)
                bar(app.UIAxes_2,1/1024*Bv)
                V = MB*sum(Vmin);
                app.UITable_2.Data = [mv',nv',rv',cv', 1/1024*Bv',MB*Vmin', RS'];
            end
            app.TotalOffchipMemoryAccessVolumeMBEditField.Value = V;
            app.Image2.Visible=false;
        end

        % Value changed function: Step3SelectModelDropDown
        function Step3SelectModelDropDownValueChanged(app, event)
            
            value = app.Step3SelectModelDropDown.Value;
            %         M	  N	R	C	K	S	P
            VGG16C= [64	  3	32	32	3	1	1;
                     64	 64	32	32	3	1	2;
                    128	 64	16	16	3	1	1;
                    128	128	16	16	3	1	2;
                    256	128	8	8	3	1	1;
                    256	256	8	8	3	1	1;
                    256	256	8	8	3	1	2;
                    512	256	4	4	3	1	1;
                    512	512	4	4	3	1	1;
                    512	512	4	4	3	1	2;
                    512	512	2	2	3	1	1;
                    512	512	2	2	3	1	1;
                    512	512	2	2	3	1	2;
                    512	512	1	1	1	1	1;
                     10	512	1	1	1	1	1];
            VGG16P= [46	  3	32	32	3	1	1;
                     64	 64	32	32	3	1	2;
                    128	 64	16	16	3	1	1;
                    128	128	16	16	3	1	2;
                    256	128	8	8	3	1	1;
                    139	256	8	8	3	1	1;
                    256	256	8	8	3	1	2;
                    306	256	4	4	3	1	1;
                    176	512	4	4	3	1	1;
                    359	512	4	4	3	1	2;
                    227	512	2	2	3	1	1;
                    216	512	2	2	3	1	1;
                    512	512	2	2	3	1	2;
                    512	512	1	1	1	1	1;
                     10	512	1	1	1	1	1];
            VGG16I= [64	  3	224	224	3	1	1;
                     64	 64	224	224	3	1	2;
                    128	 64	112	112	3	1	1;
                    128	128	112	112	3	1	2;
                    256	128	56	56	3	1	1;
                    256	256	56	56	3	1	1;
                    256	256	56	56	3	1	2;
                    512	256	28	28	3	1	1;
                    512	512	28	28	3	1	1;
                    512	512	28	28	3	1	2;
                    512	512	14	14	3	1	1;
                    512	512	14	14	3	1	1;
                   512	512	14	14	3	1	2;
                   4096	512	1	1	7	1	1;
                   4096	512	1	1	1	1	1;
                    100	4096	1	1	1	1	1];
           AlexNetC=[64	  3	8	8	3	2	2;
                    192	 64	4	4	3	1	2;
                    384	 192	2	2	3	1	1;
                    256	384	2	2	3	1	2;
                   4096	256	1	1	2	1	1;
                   4096	4096	1	1	1	1	1;
                   10	4096	1	1	1	1	1];
            AlexNet=[96	  3	55	55	11	1	2;
                    256	 96/2	27	27	5	1	2;
                    384	 256	13	13	3	1	1;
                    384	384/2	13	13	3	1	1;
                    256	384/2	13	13	3	1	2;
                   4096	256	1	1	6	1	1;
                   4096	4096	1	1	1	1	1;
                   1000	4096	1	1	1	1	1];
            LeNet =[6	  1	28	28	5	1	2;
                    12	 6	12	12	5	1	2;
                    10	 12	1	1	4	1	1];
            if(value == "VGG16-CIFAR10")
                CNN = VGG16C;
            elseif(value == "VGG16-ImageNet")
                CNN = VGG16I;
            elseif(value == "LeNet-MNIST")
                CNN = LeNet;
            elseif(value == "AlexNet-ImageNet")
                CNN = AlexNet;
            elseif(value == "AlexNet-CIFAR10")
                CNN = AlexNetC;
            elseif(value == "VGG16-64P-CIFAR10")
                CNN = VGG16P;
            end
            
            if(value ~= "Custom")
                app.UITable.Data = CNN;
            else
                app.UITable.Data = [32 3 32 32 1 1 1;
                                    zeros(19,numel(app.UITable.ColumnName))];
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Step3SelectModelDropDownLabel = uilabel(app.UIFigure);
            app.Step3SelectModelDropDownLabel.Text = 'Step 3: Select Model';
            app.Step3SelectModelDropDown = uidropdown(app.UIFigure);
            app.Step3SelectModelDropDown.Items = {'Custom', 'LeNet-MNIST', 'AlexNet-CIFAR10', 'VGG16-64P-CIFAR10', 'VGG16-CIFAR10', 'AlexNet-ImageNet', 'VGG16-ImageNet', '', ''};
            app.Step3SelectModelDropDown.ValueChangedFcn = createCallbackFcn(app, @Step3SelectModelDropDownValueChanged, true);
            app.Step3SelectModelDropDown.Value = 'Custom';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SuperSlashDesignSpaceExplorationforSingleLayerProcessingLabel = uilabel(app.UIFigure);
            app.SuperSlashDesignSpaceExplorationforSingleLayerProcessingLabel.Text = 'SuperSlash Design Space Exploration for Single Layer Processing';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Step2ChooseDataReuseStrategyRSDropDownLabel = uilabel(app.UIFigure);
            app.Step2ChooseDataReuseStrategyRSDropDownLabel.Text = 'Step 2: Choose Data Reuse Strategy (RS)';
            app.Step2ChooseDataReuseStrategyRSDropDown = uidropdown(app.UIFigure);
            app.Step2ChooseDataReuseStrategyRSDropDown.Items = {'0-Combined', '1-IRO', '2-ORO', '3-WRO'};
            app.Step2ChooseDataReuseStrategyRSDropDown.Value = '0-Combined';
            app.TotalOffchipMemoryAccessVolumeMBEditFieldLabel = uilabel(app.UIFigure);
            app.TotalOffchipMemoryAccessVolumeMBEditFieldLabel.Text = 'Total Off-chip Memory Access Volume (MB)';
            app.TotalOffchipMemoryAccessVolumeMBEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalOffchipMemoryAccessVolumeMBEditField.Editable = 'off';
            app.Step1ChooseOnchipBufferSizeKBDropDownLabel = uilabel(app.UIFigure);
            app.Step1ChooseOnchipBufferSizeKBDropDownLabel.Text = 'Step 1: Choose On-chip Buffer Size (KB)';
            app.Step1ChooseOnchipBufferSizeKBDropDown = uidropdown(app.UIFigure);
            app.Step1ChooseOnchipBufferSizeKBDropDown.Items = {'224', '192', '160', '128', '96', '64', '32', '16', '8', '4', '2', '1', '0.5', '0.25', '0.125'};
            app.Step1ChooseOnchipBufferSizeKBDropDown.Value = '32';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'M'; 'N'; 'R'; 'C'; 'K'; 'S'; 'P'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.UITable.ColumnEditable = true;
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Tm'; 'Tn'; 'Tr'; 'Tc'; 'B'; 'V'; 'RS'};
            app.UITable_2.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.UITable_2.ColumnEditable = true;
            app.LayerwiseOutputsLabel = uilabel(app.UIFigure);
            app.LayerwiseOutputsLabel.Text = 'Layer-wise Outputs';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ITU.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'VISPro.png';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Off-chip Memory Access Volume')
            xlabel(app.UIAxes, 'Layer Number (#)')
            ylabel(app.UIAxes, 'Access Volume (MB)')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'GBuff Utilization (KB)')
            xlabel(app.UIAxes_2, 'Layer Number (#)')
            ylabel(app.UIAxes_2, 'B (KB)')
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = 'processing.gif';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SuperSlash

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end